classdef youbotApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        RightPanel                    matlab.ui.container.Panel
        StopcommunicationButton       matlab.ui.control.Button
        StartcommunicationButton      matlab.ui.control.Button
        q4degSlider                   matlab.ui.control.Slider
        q4degSliderLabel              matlab.ui.control.Label
        q3degSlider                   matlab.ui.control.Slider
        q3degSliderLabel              matlab.ui.control.Label
        q2degSlider                   matlab.ui.control.Slider
        q2degSliderLabel              matlab.ui.control.Label
        q1degSlider                   matlab.ui.control.Slider
        q1degSliderLabel              matlab.ui.control.Label
        q0degSlider                   matlab.ui.control.Slider
        q0degSliderLabel              matlab.ui.control.Label
        FindandconfigyoubotarmButton  matlab.ui.control.Button
        InitializeButton              matlab.ui.control.Button
        ConfigfilefullpathEditField   matlab.ui.control.EditField
        ConfigfilefullpathEditFieldLabel  matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        youbotobj; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: FindandconfigyoubotarmButton
        function FindandconfigyoubotarmButtonPushed(app, event)
            try
                app.youbotobj.setupRobot;
                app.StartcommunicationButton.Enable='On';
            catch ME
                ME.message
            end
        end

        % Button pushed function: InitializeButton
        function InitializeButtonPushed(app, event)
            disp("Trying to initialize");
            try
                app.youbotobj = YoubotManager(app.ConfigfilefullpathEditField.Value);
                app.InitializeButton.Enable = "Off";
                app.ConfigfilefullpathEditField.Enable = "Off";
                app.FindandconfigyoubotarmButton.Enable='On';
                disp("Initialized");
            catch ME
                clear app.youbotobj;
                ME.message
            end
        end

        % Value changed function: q0degSlider
        function q0degSliderValueChanged(app, event)
            q = [app.q0degSlider.Value app.q1degSlider.Value...
                app.q2degSlider.Value app.q3degSlider.Value app.q4degSlider.Value];
            app.youbotobj.SetJointAngles(q);
        end

        % Value changed function: q1degSlider
        function q1degSliderValueChanged(app, event)
            q = [app.q0degSlider.Value app.q1degSlider.Value...
                app.q2degSlider.Value app.q3degSlider.Value app.q4degSlider.Value];
            app.youbotobj.SetJointAngles(q);
        end

        % Value changed function: q2degSlider
        function q2degSliderValueChanged(app, event)
            q = [app.q0degSlider.Value app.q1degSlider.Value...
                app.q2degSlider.Value app.q3degSlider.Value app.q4degSlider.Value];
            app.youbotobj.SetJointAngles(q);
        end

        % Value changed function: q3degSlider
        function q3degSliderValueChanged(app, event)
            q = [app.q0degSlider.Value app.q1degSlider.Value...
                app.q2degSlider.Value app.q3degSlider.Value app.q4degSlider.Value];
            app.youbotobj.SetJointAngles(q);
        end

        % Value changed function: q4degSlider
        function q4degSliderValueChanged(app, event)
            q = [app.q0degSlider.Value app.q1degSlider.Value...
                app.q2degSlider.Value app.q3degSlider.Value app.q4degSlider.Value];
            app.youbotobj.SetJointAngles(q);
        end

        % Button pushed function: StartcommunicationButton
        function StartcommunicationButtonPushed(app, event)
            if 1
                app.youbotobj.StartProcessCommunication();
                app.StopcommunicationButton.Enable='On';
                app.StartcommunicationButton.Enable='Off';
                app.q0degSlider.Enable='On';
                app.q0degSliderLabel.Enable='On';
                app.q1degSlider.Enable='On';
                app.q1degSliderLabel.Enable='On';
                app.q2degSlider.Enable='On';
                app.q2degSliderLabel.Enable='On';
                app.q3degSlider.Enable='On';
                app.q3degSliderLabel.Enable='On';
                app.q4degSlider.Enable='On';
                app.q4degSliderLabel.Enable='On';
            end
        end

        % Button pushed function: StopcommunicationButton
        function StopcommunicationButtonPushed(app, event)
            if 1
                app.youbotobj.StopProcessCommunication();
                app.StartcommunicationButton.Enable='On';
                app.StopcommunicationButton.Enable='Off';
                app.q0degSlider.Enable='Off';
                app.q0degSliderLabel.Enable='Off';
                app.q1degSlider.Enable='Off';
                app.q1degSliderLabel.Enable='Off';
                app.q2degSlider.Enable='Off';
                app.q2degSliderLabel.Enable='Off';
                app.q3degSlider.Enable='Off';
                app.q3degSliderLabel.Enable='Off';
                app.q4degSlider.Enable='Off';
                app.q4degSliderLabel.Enable='Off';
            end
        end

        % Value changing function: q0degSlider
        function q0degSliderValueChanging(app, event)
            changingValue = event.Value;
            q = [changingValue app.q1degSlider.Value...
                app.q2degSlider.Value app.q3degSlider.Value app.q4degSlider.Value];
            app.youbotobj.SetJointAngles(q);
        end

        % Value changing function: q1degSlider
        function q1degSliderValueChanging(app, event)
            changingValue = event.Value;
            q = [app.q0degSlider.Value changingValue...
                app.q2degSlider.Value app.q3degSlider.Value app.q4degSlider.Value];
            app.youbotobj.SetJointAngles(q);
        end

        % Value changing function: q2degSlider
        function q2degSliderValueChanging(app, event)
            changingValue = event.Value;
            q = [app.q0degSlider.Value app.q1degSlider.Value...
                changingValue app.q3degSlider.Value app.q4degSlider.Value];
            app.youbotobj.SetJointAngles(q);
        end

        % Value changing function: q3degSlider
        function q3degSliderValueChanging(app, event)
            changingValue = event.Value;
            q = [app.q0degSlider.Value app.q1degSlider.Value...
                app.q2degSlider.Value changingValue app.q4degSlider.Value];
            app.youbotobj.SetJointAngles(q);
        end

        % Value changing function: q4degSlider
        function q4degSliderValueChanging(app, event)
            changingValue = event.Value;
            q = [app.q0degSlider.Value app.q1degSlider.Value...
                app.q2degSlider.Value app.q3degSlider.Value changingValue];
            app.youbotobj.SetJointAngles(q);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {12, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            app.ConfigfilefullpathEditFieldLabel = uilabel(app.RightPanel);
            app.ConfigfilefullpathEditFieldLabel.Text = {'Config file'; '(full path)'};
            app.ConfigfilefullpathEditField = uieditfield(app.RightPanel, 'text');
            app.InitializeButton = uibutton(app.RightPanel, 'push');
            app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeButtonPushed, true);
            app.InitializeButton.Text = 'Initialize';
            app.FindandconfigyoubotarmButton = uibutton(app.RightPanel, 'push');
            app.FindandconfigyoubotarmButton.ButtonPushedFcn = createCallbackFcn(app, @FindandconfigyoubotarmButtonPushed, true);
            app.FindandconfigyoubotarmButton.Enable = 'off';
            app.FindandconfigyoubotarmButton.Text = 'Find and config youbot arm';
            app.q0degSliderLabel = uilabel(app.RightPanel);
            app.q0degSliderLabel.Enable = 'off';
            app.q0degSliderLabel.Text = 'q0[deg]';
            app.q0degSlider = uislider(app.RightPanel);
            app.q0degSlider.Limits = [-169 169];
            app.q0degSlider.ValueChangedFcn = createCallbackFcn(app, @q0degSliderValueChanged, true);
            app.q0degSlider.ValueChangingFcn = createCallbackFcn(app, @q0degSliderValueChanging, true);
            app.q0degSlider.Enable = 'off';
            app.q0degSlider.Value = -169;
            app.q1degSliderLabel = uilabel(app.RightPanel);
            app.q1degSliderLabel.Enable = 'off';
            app.q1degSliderLabel.Text = 'q1[deg]';
            app.q1degSlider = uislider(app.RightPanel);
            app.q1degSlider.Limits = [-65 90];
            app.q1degSlider.ValueChangedFcn = createCallbackFcn(app, @q1degSliderValueChanged, true);
            app.q1degSlider.ValueChangingFcn = createCallbackFcn(app, @q1degSliderValueChanging, true);
            app.q1degSlider.Enable = 'off';
            app.q1degSlider.Value = -65;
            app.q2degSliderLabel = uilabel(app.RightPanel);
            app.q2degSliderLabel.Enable = 'off';
            app.q2degSliderLabel.Text = 'q2[deg]';
            app.q2degSlider = uislider(app.RightPanel);
            app.q2degSlider.Limits = [-151 146];
            app.q2degSlider.MajorTicks = [-151 -131 -111 -91 -71 -51 -31 -11 9 29 49 69 89 109 129 146];
            app.q2degSlider.MajorTickLabels = {'-151', '-131', '-111', '-91', '-71', '-51', '-31', '-11', '9', '29', '49', '69', '89', '109', '129', '146'};
            app.q2degSlider.ValueChangedFcn = createCallbackFcn(app, @q2degSliderValueChanged, true);
            app.q2degSlider.ValueChangingFcn = createCallbackFcn(app, @q2degSliderValueChanging, true);
            app.q2degSlider.MinorTicks = [];
            app.q2degSlider.Enable = 'off';
            app.q2degSlider.Value = 146;
            app.q3degSliderLabel = uilabel(app.RightPanel);
            app.q3degSliderLabel.Enable = 'off';
            app.q3degSliderLabel.Text = 'q3[deg]';
            app.q3degSlider = uislider(app.RightPanel);
            app.q3degSlider.Limits = [-102.5 102.5];
            app.q3degSlider.ValueChangedFcn = createCallbackFcn(app, @q3degSliderValueChanged, true);
            app.q3degSlider.ValueChangingFcn = createCallbackFcn(app, @q3degSliderValueChanging, true);
            app.q3degSlider.Enable = 'off';
            app.q3degSlider.Value = 102.5;
            app.q4degSliderLabel = uilabel(app.RightPanel);
            app.q4degSliderLabel.Enable = 'off';
            app.q4degSliderLabel.Text = 'q4[deg]';
            app.q4degSlider = uislider(app.RightPanel);
            app.q4degSlider.Limits = [-167.5 167.5];
            app.q4degSlider.ValueChangedFcn = createCallbackFcn(app, @q4degSliderValueChanged, true);
            app.q4degSlider.ValueChangingFcn = createCallbackFcn(app, @q4degSliderValueChanging, true);
            app.q4degSlider.Enable = 'off';
            app.q4degSlider.Value = -167.5;
            app.StartcommunicationButton = uibutton(app.RightPanel, 'push');
            app.StartcommunicationButton.ButtonPushedFcn = createCallbackFcn(app, @StartcommunicationButtonPushed, true);
            app.StartcommunicationButton.Enable = 'off';
            app.StartcommunicationButton.Text = 'Start communication';
            app.StopcommunicationButton = uibutton(app.RightPanel, 'push');
            app.StopcommunicationButton.ButtonPushedFcn = createCallbackFcn(app, @StopcommunicationButtonPushed, true);
            app.StopcommunicationButton.Enable = 'off';
            app.StopcommunicationButton.Text = 'Stop communication';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = youbotApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end